classdef IntellEQApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        LoadFileButton        matlab.ui.control.Button
        filenameLabel         matlab.ui.control.Label
        PlayButton            matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
        RUNButton             matlab.ui.control.Button
        CenterPanel           matlab.ui.container.Panel
        IdentifiedGenreLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        genreLabel            matlab.ui.control.Label
        IntellEQLabel         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Fs = 44100; % sample rate
        audioIn % file to be processed
    end


    methods (Access = private)
        
        function [filename,audio,Fs] = prepareAudio(app, file, path)
            %prepareAudio Creates a vertical array of mono or stereo audio based
            %             on the file chosen by user
            
            fileToRead = strcat(path,file);
            [in, Fs] = audioread(fileToRead);
            filename = file;
            audio = in;
        end
        
        
        function netInputArray = processBands(app, in, Fs)
            
            if Fs < 22050
                in = upsample(in,2);   % upsample audio
                Fs = Fs * 2;
            end
            
            % filter parameters
            Nyq = Fs/2;
            order = 2;                             % filter order
            numOfBands = 5;
            freq = [20, 250, 500, 2000, 6000, 20000];
               
            N = length(in);
            mono = zeros(N,1);
            for n = 1:N
                mono(n,1) = in(n,1) + in(n,2);
            end
            
            in = mono;
            
            % Calculate RMS of whole file
            sigSquaredIn = in .^2;
            sigMeanIn = (1/N) * sum(sigSquaredIn);
            sigRootSquaredIn = sqrt(sigMeanIn);
            rmsIn = sigRootSquaredIn;
            
            signalBands = zeros(N, numOfBands);  % initialize array for storage of filtered signals
            bandAmplitudes = zeros(1, numOfBands+1);
             
            for band = 1:numOfBands
                Wn = [freq(band) , freq(band+1)] ./ Nyq;
                [b,a] = butter(order,Wn);
                signalBands(:, band) = filter(b,a,in);
                
                % Calculate RMS of each band
                sigSquared = signalBands(:,band) .^2;
                sigMean = (1/N) * sum(sigSquared);
                sigRootSquared = sqrt(sigMean);
                bandAmplitudes(1,band) = sigRootSquared;
            end
            
            netInputArray = zeros(1, numOfBands+1);
            
            for band = 1:numOfBands
                netInputArray(1,band) = bandAmplitudes(1,band);
            end
            netInputArray(1,numOfBands+1) = rmsIn;
            
        end
        
        function outputArray = net(app, inputArray)
            
            layer01Weights = [[ 7.55678138e+00,  7.00628683e-01, -6.07490641e-01,...
                              -9.73552750e-01, -4.71717608e+00, -6.08248609e-01,...
                               1.87583813e+00, -1.56618604e+00, -6.04614986e+00, 5.45191600e-01];
                             [-2.72841031e+00,  1.87967756e+00,  2.95629033e-01,...
                               1.06575014e+00,  2.84354044e-01, -2.22792155e-01,...
                              -1.72204718e+00, -1.87864403e+00,  2.64886718e+00,...
                              -7.63028549e+00];
                             [-7.15988579e+00,  2.17803903e-01, -2.98884837e-01,...
                               1.41803423e+00,  3.85420174e+00, -9.71147996e-01,...
                              -3.61592899e+00,  6.89734109e-01,  3.02924202e+00,...
                              -9.81011114e+00];
                             [ 2.37585433e+00, -8.99931894e-01,  1.29763936e+00,...
                              -4.24497997e-01, -1.06363702e+00,  1.17047642e+00,...
                              -3.45568486e-01,  7.83719013e-01, -2.86882117e+00,...
                              -3.24913902e-01];
                             [ 2.66996034e+00, -1.14884824e+00,  9.83933179e-02,...
                               3.49245022e-01, -1.35117615e+00,  6.48146411e-01,...
                               1.90962595e+00,  7.39837716e-01, -3.93163411e+00,...
                               7.57089673e-03];
                             [ 4.71429090e-01,  2.69577092e+00, -2.13165485e-01,...
                               9.49748330e-02, -4.49913166e-01, -1.48160476e+00,...
                              -1.21527773e+00, -3.10107516e+00, -5.50710785e+00,...
                              -1.28356532e+01]];
            
            layer12Weights = [[ 3.07382135, -3.8704413 , -0.73449023, -3.27845952,  5.45221218,-1.5184585 ];
                             [-0.21109412, -2.42172274,  1.25591789,  0.68282839,  0.17157183, 2.31150251];
                             [ 0.4658987 ,  0.09312731,  0.11464581,  0.63817888,  0.03648257,-0.55240805];
                             [-0.64101255,  0.80519867, -0.02573832, -0.12485697, -1.12587813, 0.81328038];
                             [-3.02025879,  3.10324299,  0.05490036,  1.41702694, -3.01300983,0.10613744];
                             [ 0.07386966,  1.47021817, -0.45108216, -0.73990489, -0.01781894,-1.34152153];
                             [ 2.082582  , -0.55597315,  0.16080232, -1.34087038,  1.4778207 , -1.29432297];
                             [ 0.56651643,  3.56851584, -0.56549881, -0.43523149, -0.63224706, -2.05528059];
                             [-1.63465741,  5.58531033,  2.12356842, -2.94588363, -4.91579222, 1.63292194];
                             [ 2.66742861, -5.84725218, -0.89955431,  1.71987335,  5.07123384, -2.9407033 ]];
            
%             weights = [layer01Weights, layer12Weights];
           hid = inputArray * layer01Weights;
            
            % activation function for hidden layer
            N = length(hid);
            for n = 1:N
                hid(n) = tanh(hid(n));
            end
            
            pred = hid * layer12Weights;
            
            softmax = exp(pred)/sum(exp(pred));
            
            N = length(softmax);
            outputArray = zeros(1,N);
            for n = 1:N
                outputArray(n) = round(softmax(n));
            end
        end
        
        function genre = calcGenre(app, inputArray)
            genre = 'Could not determine'
            if inputArray(1) == 1
                genre = 'Rock/Pop/Blues';
            elseif inputArray(2) == 1
                genre = 'Classical'
            elseif inputArray(3) == 1
                genre = 'Country'
            elseif inputArray(4) == 1
                genre = 'Disco'
            elseif inputArray(5) == 1
                genre = 'Hip Hop'
            elseif inputArray(6) == 1
                genre = 'Jazz'
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav';'*.mp3'});
            [filename, audio, samplerate] = prepareAudio(app,file,path);
            app.filenameLabel.Text = filename;
            app.Fs = samplerate;
            app.audioIn = audio;
            app.RUNButton.Enable = true;
            plot(app.UIAxes, app.audioIn);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.audioIn,app.Fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            inputArray = processBands(app, app.audioIn, app.Fs);
            outputArray = net(app,inputArray);
            genre = calcGenre(app,outputArray);
            app.genreLabel.Text = genre;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {408, 408, 408};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {408, 408};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {183, '1x', 127};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.filenameLabel = uilabel(app.LeftPanel);
            app.filenameLabel.Text = 'filename';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.CenterPanel = uipanel(app.GridLayout);
            app.IdentifiedGenreLabel = uilabel(app.CenterPanel);
            app.IdentifiedGenreLabel.Text = 'Identified Genre:';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '';
            app.genreLabel = uilabel(app.CenterPanel);
            app.genreLabel.Text = 'N/A';
            app.IntellEQLabel = uilabel(app.CenterPanel);
            app.IntellEQLabel.Text = 'IntellEQ';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time (samples)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntellEQApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end